classdef modificador_voz_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        ModificadordeVozenTiempoRealLabel  matlab.ui.control.Label
        GuardarAudioButton      matlab.ui.control.Button
        ReproducirButton        matlab.ui.control.Button
        AplicarEfectosButton    matlab.ui.control.Button
        SliderPitchShift        matlab.ui.control.Slider
        PitchLabel              matlab.ui.control.Label
        SliderIntensidadEco     matlab.ui.control.Slider
        EcoLabel                matlab.ui.control.Label
        SliderFrecuenciaCorte   matlab.ui.control.Slider
        CargarAudioButton       matlab.ui.control.Button
        FrecuenciadeCorteLabel  matlab.ui.control.Label
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        audio_original % Description
        audio_modificado
        Fs
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarAudioButton
        function CargarAudioButtonPushed(app, event)
            [file, path] = uigetfile('*.wav');
    if isequal(file,0)
        return;
    end
    [audio, Fs] = audioread(fullfile(path, file));
    app.audio_original = mean(audio, 2); % Convertir a mono
    app.Fs = Fs;
    plot(app.UIAxes, app.audio_original);
    title(app.UIAxes, 'Forma de Onda');
        end

        % Button pushed function: AplicarEfectosButton
        function AplicarEfectosButtonPushed(app, event)
    if isempty(app.audio_original)
        uialert(app.UIFigure, 'Carga un audio primero.', 'Error');
        return;
    end

    audio = app.audio_original;
    Fs = app.Fs;

    % Obtener parámetros de sliders
    Fc = abs(app.SliderFrecuenciaCorte.Value); % Frecuencia de corte
    alpha = app.SliderIntensidadEco.Value;     % Intensidad de eco
    pitch_factor = abs(app.SliderPitchShift.Value); % Factor de pitch shift

    % --- Filtro pasa bajos con protección ---
    if Fc >= Fs / 2
        Fc = (Fs / 2) - 1;
    elseif Fc < 1
        Fc = 1;
    end

    try
        [b, a] = butter(6, Fc / (Fs / 2));
        audio = filter(b, a, audio);
    catch
        warning('Error en filtro pasa bajos. Se omite el filtro.');
    end

    % --- Eco ---
    delay_segundos = 0.3;
    delay_muestras = round(delay_segundos * Fs);
    if delay_muestras > length(audio)
        delay_muestras = length(audio) - 1;
    end
    if delay_muestras > 0 && abs(alpha) > 0
        audio_eco = audio;
        audio_eco(delay_muestras+1:end) = ...
            audio_eco(delay_muestras+1:end) + alpha * audio(1:end - delay_muestras);
        audio = audio_eco;
    end

    % --- Interpolación lineal adicional (antes de pitch shift) ---
    factor_interp = 1.25;  % Puedes modificar este factor
    x_original = 1:length(audio);
    x_nuevo = linspace(1, length(audio), round(length(audio) * factor_interp));
    audio_interp = interp1(x_original, audio, x_nuevo, 'linear');
    audio_interp(isnan(audio_interp)) = 0;

    % --- Pitch Shift con interpolación ---
    if pitch_factor < 0.01
        pitch_factor = 0.01;
    elseif pitch_factor > 10
        pitch_factor = 10;
    end

    t_original = linspace(0, length(audio_interp)/Fs, length(audio_interp));
    t_nuevo = linspace(0, t_original(end), round(length(audio_interp)/pitch_factor));
    audio_modificado = interp1(t_original, audio_interp, t_nuevo, 'linear');
    audio_modificado(isnan(audio_modificado)) = 0;

    % --- Normalizar salida ---
    audio_modificado = audio_modificado / max(abs(audio_modificado) + 1e-6);

    app.audio_modificado = audio_modificado;

    % --- Mostrar waveform ---
    cla(app.UIAxes);
    plot(app.UIAxes, audio_modificado);
    title(app.UIAxes, 'Audio Modificado');
    xlabel(app.UIAxes, 'Muestras');
    ylabel(app.UIAxes, 'Amplitud');

    % --- Mostrar espectrograma ---
    cla(app.UIAxes2);
    [~, F, T, P] = spectrogram(audio_modificado, 256, [], [], Fs, 'yaxis');
    P_dB = 10 * log10(P + eps);
    imagesc(app.UIAxes2, T, F, P_dB);
    axis(app.UIAxes2, 'xy');
    xlabel(app.UIAxes2, 'Tiempo (s)');
    ylabel(app.UIAxes2, 'Frecuencia (Hz)');
    title(app.UIAxes2, 'Espectrograma');
    colormap(app.UIAxes2, jet);
    colorbar(app.UIAxes2);
        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
        if isempty(app.audio_modificado)
        uialert(app.UIFigure, 'Aplica los efectos primero.', 'Error');
        return;
        end

        % Reproducir el audio modificado
        sound(app.audio_modificado, app.Fs); % Asegúrate de que `Fs` sea el de la grabación original
        end

        % Button pushed function: GuardarAudioButton
        function GuardarAudioButtonPushed(app, event)
             if isempty(app.audio_modificado)
        uialert(app.UIFigure, 'Aplica los efectos primero.', 'Error');
        return;
             end
        [file, path] = uiputfile('*.wav');
        if isequal(file,0)
        return;
        end
        audiowrite(fullfile(path, file), app.audio_modificado, app.Fs);
        uialert(app.UIFigure, 'Audio guardado exitosamente.', 'Éxito');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            colormap(app.UIAxes2, 'winter')
            app.FrecuenciadeCorteLabel = uilabel(app.UIFigure);
            app.FrecuenciadeCorteLabel.Text = 'Frecuencia';
            app.CargarAudioButton = uibutton(app.UIFigure, 'push');
            app.CargarAudioButton.ButtonPushedFcn = createCallbackFcn(app, @CargarAudioButtonPushed, true);
            app.CargarAudioButton.Text = 'Cargar Audio';
            app.SliderFrecuenciaCorte = uislider(app.UIFigure);
            app.SliderFrecuenciaCorte.Limits = [0 8000];
            app.EcoLabel = uilabel(app.UIFigure);
            app.EcoLabel.Text = 'Eco';
            app.SliderIntensidadEco = uislider(app.UIFigure);
            app.SliderIntensidadEco.Limits = [0 1];
            app.PitchLabel = uilabel(app.UIFigure);
            app.PitchLabel.Text = 'Pitch';
            app.SliderPitchShift = uislider(app.UIFigure);
            app.SliderPitchShift.Limits = [0 2];
            app.AplicarEfectosButton = uibutton(app.UIFigure, 'push');
            app.AplicarEfectosButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarEfectosButtonPushed, true);
            app.AplicarEfectosButton.Text = 'Aplicar Efectos';
            app.ReproducirButton = uibutton(app.UIFigure, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.GuardarAudioButton = uibutton(app.UIFigure, 'push');
            app.GuardarAudioButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarAudioButtonPushed, true);
            app.GuardarAudioButton.Text = 'Guardar Audio';
            app.Panel = uipanel(app.UIFigure);
            app.ModificadordeVozenTiempoRealLabel = uilabel(app.Panel);
            app.ModificadordeVozenTiempoRealLabel.Text = 'Modificador de Voz en Tiempo Real';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modificador_voz_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end